classdef BEVapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ViewMenu                      matlab.ui.container.Menu
        HideShowModelPreviewMenu      matlab.ui.container.Menu
        ShowMenu                      matlab.ui.container.Menu
        HideMenu                      matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        InAppHelpMenu                 matlab.ui.container.Menu
        OpenHelpFileMenu              matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayoutLeft                matlab.ui.container.GridLayout
        ComponentsPanel               matlab.ui.container.Panel
        GridLayoutComponent           matlab.ui.container.GridLayout
        VehiclePlatformPanel          matlab.ui.container.Panel
        GridLayoutVehicle             matlab.ui.container.GridLayout
        ConfigDropDown                matlab.ui.control.DropDown
        DesignScenarioLabel           matlab.ui.control.Label
        JsonDesc                      matlab.ui.control.Button
        VehicleTemplateDropDown       matlab.ui.control.DropDown
        VehConfigLabel                matlab.ui.control.Label
        BEVModelDropDown              matlab.ui.control.DropDown
        BaseModelDropDownLabel        matlab.ui.control.Label
        VehPlatDesc                   matlab.ui.control.Button
        BEVmodelDesc                  matlab.ui.control.Button
        CenterPanel                   matlab.ui.container.Panel
        DescriptionGrid               matlab.ui.container.GridLayout
        selectionPreviewPanel         matlab.ui.container.Panel
        GridLayoutPreview             matlab.ui.container.GridLayout
        HideShowButton                matlab.ui.control.StateButton
        ModelNameLabel                matlab.ui.control.Label
        PreviewImage                  matlab.ui.control.Image
        DescriptionPanel              matlab.ui.container.Panel
        GridLayoutDesc                matlab.ui.container.GridLayout
        HTML                          matlab.ui.control.HTML
        RightPanel                    matlab.ui.container.Panel
        GridLayoutRight               matlab.ui.container.GridLayout
        CreateModelButton             matlab.ui.control.Button
        ParameterExportButton         matlab.ui.control.Button
        ModelExportButton             matlab.ui.control.Button
        DriverSetupPanel              matlab.ui.container.Panel
        GridLayoutDriver              matlab.ui.container.GridLayout
        Panel                         matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        DriveCycleDesc                matlab.ui.control.Button
        ControlDesc                   matlab.ui.control.Button
        DriveCycleDropDown            matlab.ui.control.DropDown
        DriveCycleDropDownLabel       matlab.ui.control.Label
        ControlSelectionDropDown      matlab.ui.control.DropDown
        ControlSelectionLabel         matlab.ui.control.Label
        ChargingButton                matlab.ui.control.StateButton
        RegenButton                   matlab.ui.control.StateButton
        AWDButton                     matlab.ui.control.StateButton
        HVACPanel                     matlab.ui.container.Panel
        GridLayoutHhvac               matlab.ui.container.GridLayout
        ACstatusLabel                 matlab.ui.control.Label
        CabindegCLabel                matlab.ui.control.Label
        CabinTempSetpointEditField    matlab.ui.control.NumericEditField
        CabinTempSetpointLabel        matlab.ui.control.Label
        ACButton                      matlab.ui.control.StateButton
        EnvInitialConditionPanel      matlab.ui.container.Panel
        GridLayoutEnv                 matlab.ui.container.GridLayout
        barLabel                      matlab.ui.control.Label
        degCLabel                     matlab.ui.control.Label
        CO2FractionInitialEditField   matlab.ui.control.NumericEditField
        CO2FractionInitialEditFieldLabel  matlab.ui.control.Label
        RelHumidityInitEditField      matlab.ui.control.NumericEditField
        RelativeHumidityInitialLabel  matlab.ui.control.Label
        AmbPressInitEditField         matlab.ui.control.NumericEditField
        AmbientPressureInitialLabel   matlab.ui.control.Label
        AmbTempEditField              matlab.ui.control.NumericEditField
        AmbTempLabel                  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties  % default is public
        ComponentDropdowns; % struct to hold handles to each dropdown
        ComponentSlider;      % handle to the uislider
        ComponentButtons;     % struct to hold handles to each action buttons
        

    end
 methods (Access = public)
            function projectRoot = getBEVProjectRoot(app)
            %GETBEVPROJECTROOT Safely returns the BEV project root folder
            %   Checks if a MATLAB project is loaded and returns its root folder.
            %   If not, displays a UI alert and throws an error.

            try
                proj = matlab.project.rootProject;
                projectRoot = proj.RootFolder; % Full path to .prj folder
            catch
                uialert(app.UIFigure, "BEV project was not loaded.", "Error", ...
                    "Icon", "error");
                error("BEVApp:ProjectNotLoaded", "BEV project was not loaded.");
            end
            end
 end

    methods (Access = private)

        function descHTML = descTextHTML(~,desc)
            % Replace line breaks with <br>
            descHTML = strrep(desc, newline, '<br>');

            % Optional: replace markdown-style bold with <b>...</b>
            descHTML = regexprep(descHTML, '\*\*(.*?)\*\*', '<b>$1</b>');

            % Wrap in HTML
            descHTML = ['<html><body style="font-size:14px; style="font-family:Helvetica;">' descHTML '</body></html>'];
        end

        function ModelDescription(app,modelName)
            modelBaseName = erase(convertStringsToChars(modelName), '.slx');

            % Create unique PNG file name in temp folder
            timestamp = datestr(now, 'yyyymmddTHHMMSSFFF');
            tempPng = fullfile(tempdir, [modelBaseName, '_', timestamp, '.png']);
            root =  getBEVProjectRoot(app);
            previewPng = fullfile(root, 'preview.png');

            try
                % Load and print
                load_system(modelName);
                print(['-s', modelBaseName], '-dpng', tempPng);
                print(['-s', modelBaseName], '-dpng', previewPng);
                % Fetch the model description
                desc = get_param(modelBaseName, 'Description');
                descHTML = descTextHTML(app,desc);
                app.HTML.HTMLSource = descHTML;
                close_system(modelName, 0);

                % Update image
                app.selectionPreviewPanel.Visible = 'on';
                app.DescriptionGrid.RowHeight = {'1x','1x'};               
                app.PreviewImage.Visible = 'on';
                app.PreviewImage.ImageSource = tempPng;
                selectionPreviewStatus(app);
                % Pause to allow UI update,
                pause(1);
                %  delete the temp file
                delete(tempPng);

            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
            end

            app.UIFigure.Pointer = 'arrow';
            app.ModelNameLabel.Text = modelName;
        end

        function showEnlargedImage(~, imagePath)
            % Create a new non-modal figure window
            popupFig = uifigure('Name', 'Model Preview', ...
                'Position', [100, 100, 800, 600]);  % no 'WindowStyle','modal'

            % Add a grid layout to handle resizing
            gl = uigridlayout(popupFig, [1 1]);
            gl.RowHeight = {'1x'};
            gl.ColumnWidth = {'1x'};

            % Add image to the grid so it scales automatically
            img = uiimage(gl);
            img.ImageSource = imagePath;
            img.ScaleMethod = 'fit';   % maintain aspect ratio
            img.Layout.Row = 1;
            img.Layout.Column = 1;
        end


        function config = loadTemplateConfig(~,jsonFile)
            fid = fopen(jsonFile);
            raw = fread(fid, inf);
            str = char(raw');
            fclose(fid);
            config = jsondecode(str);
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % App name
            app.UIFigure.Name = 'BEV Model Setup';

            % Monitor and scaling info
            monitors = get(0,'MonitorPositions');   % Nx4: [left bottom width height]
            primaryMon = monitors(1,:);                    % primary monitor

            % Default position and size
            defaultPos = [100 100 1098 580];       % [x y width height]

            % Check if default fits in current monitor
            fitsInMonitor = ...
                defaultPos(1) + defaultPos(3) <= primaryMon(1) + primaryMon(3) && ...
                defaultPos(2) + defaultPos(4) <= primaryMon(2) + primaryMon(4);

            if fitsInMonitor
                % Use default size
                app.UIFigure.Units = 'pixels';
                app.UIFigure.Position = defaultPos;
            else
                % Use scaled size (75% of monitor)
                frac = 0.75;
                width  = round(primaryMon(3) * frac);
                height = round(primaryMon(4) * frac);
                x = round(primaryMon(1) + (primaryMon(3) - width)/2);
                y = round(primaryMon(2) + (primaryMon(4) - height)/2);
                app.UIFigure.Units = 'pixels';
                app.UIFigure.Position = [x y width height];
            end

            % Get required paths in folder structure and error out if
            % project not loaded
            projectRoot = getBEVProjectRoot(app);
            modelFolder = fullfile(projectRoot, 'Model');
            templateFolder = fullfile(modelFolder, 'VehicleTemplate');
            controlFolder = fullfile(projectRoot, 'Components\Controller\Model');

            % get slx files for dropdown
            app.BEVModelDropDown.Items = getSLXFiles(modelFolder);
            app.BEVModelDropDown.Value = app.BEVModelDropDown.Items{1};
            
            % get vehicle template for dropdown
            app.VehicleTemplateDropDown.Items = getSLXFiles(templateFolder);
            try
                app.VehicleTemplateDropDown.Value = app.VehicleTemplateDropDown.Items{1};
                app.ModelNameLabel.Text = app.BEVModelDropDown.Items{1};
            catch
                uialert(app.UIFigure, "No template found in the template folder", "Error");
                return;
            end

            % get json files for dropdown
            app.ConfigDropDown.Items = getJSONFiles(modelFolder);
            cfgIdx = find(app.ConfigDropDown.Items == "VehicleTemplateConfig.json");
            if ~isempty(cfgIdx)
                 app.ConfigDropDown.Value = app.ConfigDropDown.Items{cfgIdx(1)};
            elseif ~isempty(app.ConfigDropDown.Items)
                app.ConfigDropDown.Value = app.ConfigDropDown.Items{1};
            else
                uialert(app.UIFigure, ['No vehicle design scenario present ' ...
                    'in vehicle template folder'], 'Error');
                return;
            end
            
            % get control templates from dropdown
            app.ControlSelectionDropDown.Items = getSLXFiles(controlFolder);
            try
                app.ControlSelectionDropDown.Value = app.ControlSelectionDropDown.Items{1};
            catch
                uialert(app.UIFigure, "No controller template found in the controller folder", "Error");
                return;
            end

            % Create component Dropdowns
            createComponentDropdowns(app);
            controlSelectionDropdown(app);
            InAppHelpMenuSelected(app);
            driveCycleSetup(app);
            
        end

        % Size changed function: selectionPreviewPanel
        function BEVModelDropDownClicked(app, event)
            BEVModelDropDownValueChanged(app);
        end

        % Callback function: DescriptionPanel, PreviewImage
        function PreviewImageClicked(app, event)
            proj = matlab.project.rootProject;   % gets the current project object
            previewPng = fullfile(proj.RootFolder, 'preview.png');
            showEnlargedImage(app, previewPng);
        end

        % Button pushed function: JsonDesc
        function JsonDescButtonPushed(app, event)
            % Parse config and current template
            value = app.ConfigDropDown.Value;
            cfg = jsondecode(fileread(value));
            tmpl = erase(app.VehicleTemplateDropDown.Value, '.slx');

            % Build list of available templates (as a string scalar)
            tmplNames = string(fieldnames(cfg));
            tmplList  = strjoin(tmplNames, ", ");

            % Callout box (rounded, light bg, thin border, vertical orange stripe)
            availLine = ...
                "<div style=""position:relative;" + ...
                "background:#eeeeee;" + ...          % light warm bg
                "border:1px solid #F1D5BF;" + ...     % subtle border
                "border-radius:10px;" + ...
                "padding:10px 12px 10px 16px;" + ...
                "margin:8px 0;" + ...
                "font-size:12px;"">" + ...
                "<div style=""position:absolute;left:4px;top:6px;bottom:6px;" + ...
                "width:6px;background:#D95319;border-radius:6px;""></div>" + ...
                "<div><b>Available templates in config- " + ...
                "<span style=""font-family:monospace"">" + value + "</span>:</b></br>" + ...
                "<span style=""font-weight:600;font-family:monospace;color:#155724"">" + ...
                tmplList + ...
                "</span>" + ...
                "</div>" + ...
                "</div>";

            % Selected template line (use a contrasting navy)
            headerLine = "<p><b>For the template selected: " + ...
                "<span style=""color:#d95319"">" + tmpl + "</span>" + ...
                "</b></p>";

            % Description tree (ensure single string scalar)
            inner = buildList(cfg.(tmpl));
            inner = strjoin(string(inner), "");   % flatten if array

            % Assemble final HTML as a single string scalar
            html = "<html><body style=""font-family:system-ui,Segoe UI,Arial,sans-serif;font-size:13px"">" + ...
                availLine + headerLine + inner + ...
                "</body></html>";

            % Assign (HTMLSource needs char or string scalar)
            app.HTML.HTMLSource = char(html);

            % Layout
            app.selectionPreviewPanel.Visible = 'off';
            app.DescriptionGrid.RowHeight = {'0x','1x'};

            % Disable view menu
            app.HideMenu.Enable = "off";
            app.ShowMenu.Enable = "off";
        end

        % Button pushed function: VehPlatDesc
        function VehPlatDescButtonPushed(app, event)
            % Start spinner-style progress dialog
            d = uiprogressdlg(app.UIFigure, ...
                'Title','Please wait', ...
                'Message','Loading Vehicle configuration description…', ...
                'Indeterminate','on', ...
                'Cancelable','off');

            try


                % Get selected model name
                modelName = app.VehicleTemplateDropDown.Value;
                ModelDescription(app,modelName);


                % done
                close(d);
            catch ME
                if isvalid(d), close(d); end
                rethrow(ME);
            end

        end

        % Button pushed function: BEVmodelDesc
        function BEVmodelDescButtonPushed(app, event)
            % Start spinner-style progress dialog
            d = uiprogressdlg(app.UIFigure, ...
                'Title','Please wait', ...
                'Message','Loading model description…', ...
                'Indeterminate','on', ...
                'Cancelable','off');
            modelName =  app.BEVModelDropDown.Value;
            ModelDescription(app,modelName);

            close(d);
        end

        % Value changed function: HideShowButton
        function HideShowButtonValueChanged(app, event)
            selectionPreviewStatus(app)
        end

        % Menu selected function: ShowMenu
        function ShowMenuSelected(app, event)
            app.HideShowButton.Value = false;
            selectionPreviewStatus(app);

        end

        % Menu selected function: HideMenu
        function HideMenuSelected(app, event)
            app.HideShowButton.Value = true;
            selectionPreviewStatus(app);
        end

        % Menu selected function: InAppHelpMenu
        function InAppHelpMenuSelected(app, event)
            % Hide the preview…
            app.selectionPreviewPanel.Visible = 'off';
            % …and collapse its row to zero, giving full space to the description
            app.DescriptionGrid.RowHeight = {'0x','1x'};

            % load the help file
            app.HTML.HTMLSource = 'helpApp.html';

            % Disable view menu
            app.HideMenu.Enable = "off";
            app.ShowMenu.Enable = "off";


        end

        % Menu selected function: OpenHelpFileMenu
        function OpenHelpFileMenuSelected(app, event)
            web('helpAppDetail.html');
        end

        % Value changed function: VehicleTemplateDropDown
        function VehicleTemplateDropDownValueChanged(app, event)
            % Start spinner-style progress dialog
            d = uiprogressdlg(app.UIFigure, ...
                'Title','Please wait', ...
                'Message','Loading vehicle configuration and components…', ...
                'Indeterminate','on', ...
                'Cancelable','off');

            try

                % Get selected model name
                createComponentDropdowns(app);
                controlSelectionDropdown(app);
                modelName = app.VehicleTemplateDropDown.Value;
                ModelDescription(app,modelName);

                % done
                close(d);
            catch ME
                if isvalid(d), close(d); end
                rethrow(ME);
            end
        end

        % Value changed function: BEVModelDropDown
        function BEVModelDropDownValueChanged(app, event)
            % Start spinner-style progress dialog
            d = uiprogressdlg(app.UIFigure, ...
                'Title','Please wait', ...
                'Message','Loading base model and the components…', ...
                'Indeterminate','on', ...
                'Cancelable','off');

            try
                % Get selected model name
                modelName = app.BEVModelDropDown.Value;
                ModelDescription(app,modelName);

                driveCycleSetup(app);
                createComponentDropdowns(app);
                controlSelectionDropdown(app);
                % done
                close(d);
            catch ME
                if isvalid(d), close(d); end
                rethrow(ME);
            end
             
        end

        % Button pushed function: ModelExportButton
        function ModelExportButtonPushed(app, event)
            % Start spinner-style progress dialog
            d = uiprogressdlg(app.UIFigure, ...
                'Title','Please wait', ...
                'Message','Creating model setup script…', ...
                'Indeterminate','on', ...
                'Cancelable','off');

            try
                outFile = 'BEVmodelCreater';
                exportSetupScript(app, outFile);
                root = getBEVProjectRoot(app);                           % project root
                modelDir = fullfile(root, 'Model');                % fixed location
                outFile = fullfile(modelDir, [outFile '.m']);  % final path (always .m)
                open(outFile);

                % done
                close(d);
            catch ME
                if isvalid(d), close(d); end
                rethrow(ME);
            end

        end

        % Value changed function: ConfigDropDown
        function ConfigDropDownValueChanged(app, event)
            % Start spinner-style progress dialog
            d = uiprogressdlg(app.UIFigure, ...
                'Title','Please wait', ...
                'Message','Loading components for the configuration selected…', ...
                'Indeterminate','on', ...
                'Cancelable','off');
            % try
            %     JsonDescButtonPushed(app);
            %     validateVehicleConfig(app)
            % catch
            %     uialert(app.UIFigure, "Structure of the config json file doesn't have valid structure. ..." + ...
            %         "Check help document", "Error");
            %     % return;
            % end

            try
                createComponentDropdowns(app);
                controlSelectionDropdown(app);
                JsonDescButtonPushed(app);
                % done
                close(d);
            catch ME
                if isvalid(d), close(d); end
                rethrow(ME);
            end


        end

        % Button pushed function: CreateModelButton
        function CreateModelButtonPushed(app, event)


            try
                % param config function call to make sure that all the
                % params are connected properly.
                ParamConfigButtonPushed(app);

                 % Start spinner-style progress dialog
                 d = uiprogressdlg(app.UIFigure, ...
                'Title','Please wait', ...
                'Message','Creating model setup script and loading model…', ...
                'Indeterminate','on', ...
                'Cancelable','off');

                outFileModel = 'BEVmodelCreater';
                exportSetupScript(app, outFileModel);
                root = getBEVProjectRoot(app);                          % project root
                modelDir = fullfile(root, 'Model');                % fixed location
                modelName = erase(app.BEVModelDropDown.Value,'.slx');
                outFileParam = fullfile(modelDir,[modelName '_setup.m']);
                outFileModel = fullfile(modelDir, [outFileModel '.m']);  % final path (always .m)
                pause(1);
                open(outFileParam);run(outFileModel);
                evalin('base', sprintf('run("%s")', outFileParam));
                modelDashboardSetup(app);
                % done
                close(d);
            catch ME
                if isvalid(d), close(d); end
                rethrow(ME);
            end

        end

        % Value changed function: ACButton
        function ACButtonValueChanged(app, event)
            value = app.ACButton.Value;
            if value == true
                app.ACButton.BackgroundColor = [0.63,0.94,0.60];
                app.ACButton.Text = "On";
            else
                app.ACButton.BackgroundColor = [0.96,0.84,0.84];
                app.ACButton.Text = "Off";
            end
        end

        % Value changed function: AWDButton
        function AWDButtonValueChanged(app, event)
            % if charging button is pressed deactivate controls
            if app.ChargingButton.Value == true
                app.AWDButton.Value = false;
            end
            value = app.AWDButton.Value;
            if value == true
                app.AWDButton.BackgroundColor = [0.63,0.94,0.60];
                app.AWDButton.Text = "AWD On";
                
            else
                app.AWDButton.BackgroundColor = [0.96,0.84,0.84];
                app.AWDButton.Text = "AWD Off";
            end
        end

        % Value changed function: RegenButton
        function RegenButtonValueChanged(app, event)
            % if charging button is pressed deactivate controls
            if app.ChargingButton.Value == true
                app.RegenButton.Value = false;
            end
            value = app.RegenButton.Value;
            if value == true
                app.RegenButton.BackgroundColor = [0.63,0.94,0.60];
                app.RegenButton.Text = "Regen On";
            else
                app.RegenButton.BackgroundColor = [0.96,0.84,0.84];
                app.RegenButton.Text = "Regen Off";
            end
        end

        % Value changed function: ChargingButton
        function ChargingButtonValueChanged(app, event)
            value = app.ChargingButton.Value;
            if value == true
                app.ChargingButton.BackgroundColor = [0.63,0.94,0.60];
                app.ChargingButton.Text = "Charging On";
                app.RegenButton.Value = false;
                app.RegenButtonValueChanged();
                app.RegenButton.Enable = "off";
                app.AWDButton.Value = false;
                app.AWDButtonValueChanged();
                app.AWDButton.Enable = "off";
            else
                app.ChargingButton.BackgroundColor = [0.96,0.84,0.84];
                app.ChargingButton.Text = "Charging Off";
                app.AWDButton.Enable = "on";
                app.RegenButton.Enable = "on";
            end
        end

        % Value changed function: DriveCycleDropDown
        function DriveCycleDropDownValueChanged(app, event)
            % value = app.DriveCycleDropDown.Value;
        end

        % Button pushed function: ParameterExportButton
        function ParameterExportButtonPushed(app, event)
            %% Write the param script
            ParamConfigButtonPushed(app);
        end

        % Button pushed function: ControlDesc
        function ControlDescButtonPushed(app, event)
             % Start spinner-style progress dialog
            d = uiprogressdlg(app.UIFigure, ...
                'Title','Please wait', ...
                'Message','Loading delected controls description…', ...
                'Indeterminate','on', ...
                'Cancelable','off');

            try


                % Get selected model name
                controlName = app.ControlSelectionDropDown.Value;
                ModelDescription(app,controlName);


                % done
                close(d);
            catch ME
                if isvalid(d), close(d); end
                rethrow(ME);
            end

        end

        % Button pushed function: DriveCycleDesc
        function DriveCycleDescButtonPushed(app, event)
             % Start spinner-style progress dialog
            d = uiprogressdlg(app.UIFigure, ...
                'Title','Please wait', ...
                'Message','Loading delected controls description…', ...
                'Indeterminate','on', ...
                'Cancelable','off');
            try
                % write the driver block description
                desc = [ ...
                    'Drive cycle source block is used to set the target velocity for the vehicle.' newline newline...
                    '⚡ Main Features:' newline ...
                    '* The Drive Cycle Source block generates a standard or user-specified longitudinal drive cycle.' newline newline ...
                    '📤 Outputs: The block output is the vehicle longitudinal reference speed as a function of time.' newline newline...
                    '📌 Note: The block includes the FTP–75 drive cycle by default. ...' ...
                    'You need to  install additional drive cycles from a support package for some workflows to work.' ...
                    ];
                descHTML = descTextHTML(app,desc);
                app.HTML.HTMLSource = descHTML;
                close(d);

                % Layout
                app.selectionPreviewPanel.Visible = 'off';
                app.DescriptionGrid.RowHeight = {'0x','1x'};

                % Disable view menu
                app.HideMenu.Enable = "off";
                app.ShowMenu.Enable = "off";

            catch ME
                if isvalid(d), close(d); end
                rethrow(ME);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {574, 574, 574};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {574, 574};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {344, '1x', 325};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'BEV Model Setup';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.HideShowModelPreviewMenu = uimenu(app.ViewMenu);
            app.HideShowModelPreviewMenu.Text = 'Hide/Show Model Preview';
            app.ShowMenu = uimenu(app.HideShowModelPreviewMenu);
            app.ShowMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowMenuSelected, true);
            app.ShowMenu.Tooltip = {'Show the preview of the model selected'};
            app.ShowMenu.Accelerator = 's';
            app.ShowMenu.Text = 'Show';
            app.HideMenu = uimenu(app.HideShowModelPreviewMenu);
            app.HideMenu.MenuSelectedFcn = createCallbackFcn(app, @HideMenuSelected, true);
            app.HideMenu.Tooltip = {'Hide the preview of the model selected'};
            app.HideMenu.Enable = 'off';
            app.HideMenu.Separator = 'on';
            app.HideMenu.Accelerator = 'h';
            app.HideMenu.Text = 'Hide';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.InAppHelpMenu = uimenu(app.HelpMenu);
            app.InAppHelpMenu.MenuSelectedFcn = createCallbackFcn(app, @InAppHelpMenuSelected, true);
            app.InAppHelpMenu.Accelerator = 'i';
            app.InAppHelpMenu.Text = 'In-App Help';
            app.OpenHelpFileMenu = uimenu(app.HelpMenu);
            app.OpenHelpFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenHelpFileMenuSelected, true);
            app.OpenHelpFileMenu.Text = 'Open Help File';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayoutLeft = uigridlayout(app.LeftPanel);
            app.VehiclePlatformPanel = uipanel(app.GridLayoutLeft);
            app.VehiclePlatformPanel.Tooltip = {'Select the model for the EV and the vehicle configuration from the list available.'};
            app.VehiclePlatformPanel.Title = 'Vehicle Configuration';
            app.GridLayoutVehicle = uigridlayout(app.VehiclePlatformPanel);
            app.BEVmodelDesc = uibutton(app.GridLayoutVehicle, 'push');
            app.BEVmodelDesc.ButtonPushedFcn = createCallbackFcn(app, @BEVmodelDescButtonPushed, true);
            app.BEVmodelDesc.Tooltip = {'Click to see the description of model selected'};
            app.BEVmodelDesc.Text = '?';
            app.VehPlatDesc = uibutton(app.GridLayoutVehicle, 'push');
            app.VehPlatDesc.ButtonPushedFcn = createCallbackFcn(app, @VehPlatDescButtonPushed, true);
            app.VehPlatDesc.Tooltip = {'Click to see the description of vehicle platform selected'};
            app.VehPlatDesc.Text = '?';
            app.BaseModelDropDownLabel = uilabel(app.GridLayoutVehicle);
            app.BaseModelDropDownLabel.Text = 'Base Model';
            app.BEVModelDropDown = uidropdown(app.GridLayoutVehicle);
            app.BEVModelDropDown.ValueChangedFcn = createCallbackFcn(app, @BEVModelDropDownValueChanged, true);
            app.BEVModelDropDown.Tooltip = {'Select the model to setup'};
            app.VehConfigLabel = uilabel(app.GridLayoutVehicle);
            app.VehConfigLabel.Text = 'Vehicle Config';
            app.VehicleTemplateDropDown = uidropdown(app.GridLayoutVehicle);
            app.VehicleTemplateDropDown.ValueChangedFcn = createCallbackFcn(app, @VehicleTemplateDropDownValueChanged, true);
            app.VehicleTemplateDropDown.Tooltip = {'Select the vehicle cofiguration for the base model that defines the model structure.'};
            app.JsonDesc = uibutton(app.GridLayoutVehicle, 'push');
            app.JsonDesc.ButtonPushedFcn = createCallbackFcn(app, @JsonDescButtonPushed, true);
            app.JsonDesc.Tooltip = {'Click to see the configuration'};
            app.JsonDesc.Text = '?';
            app.DesignScenarioLabel = uilabel(app.GridLayoutVehicle);
            app.DesignScenarioLabel.Text = 'Design Scenario';
            app.ConfigDropDown = uidropdown(app.GridLayoutVehicle);
            app.ConfigDropDown.ValueChangedFcn = createCallbackFcn(app, @ConfigDropDownValueChanged, true);
            app.ConfigDropDown.Tooltip = {'Select a predifened scenario that specifies component fidelities and settings'};
            app.ComponentsPanel = uipanel(app.GridLayoutLeft);
            app.ComponentsPanel.Tooltip = {'List of all the availble component configuration and fidelity'};
            app.ComponentsPanel.Title = 'Component Selection';
            app.GridLayoutComponent = uigridlayout(app.ComponentsPanel);
            app.CenterPanel = uipanel(app.GridLayout);
            app.DescriptionGrid = uigridlayout(app.CenterPanel);
            app.DescriptionPanel = uipanel(app.DescriptionGrid);
            app.DescriptionPanel.Title = 'Description';
            app.DescriptionPanel.SizeChangedFcn = createCallbackFcn(app, @PreviewImageClicked, true);
            app.GridLayoutDesc = uigridlayout(app.DescriptionPanel);
            app.HTML = uihtml(app.GridLayoutDesc);
            app.selectionPreviewPanel = uipanel(app.DescriptionGrid);
            app.selectionPreviewPanel.Title = 'Selection Preview';
            app.selectionPreviewPanel.SizeChangedFcn = createCallbackFcn(app, @BEVModelDropDownClicked, true);
            app.GridLayoutPreview = uigridlayout(app.selectionPreviewPanel);
            app.PreviewImage = uiimage(app.GridLayoutPreview);
            app.PreviewImage.ImageClickedFcn = createCallbackFcn(app, @PreviewImageClicked, true);
            app.PreviewImage.Tooltip = {'Preview of the model canvas'};
            app.ModelNameLabel = uilabel(app.GridLayoutPreview);
            app.ModelNameLabel.Text = 'Model Name';
            app.HideShowButton = uibutton(app.GridLayoutPreview, 'state');
            app.HideShowButton.ValueChangedFcn = createCallbackFcn(app, @HideShowButtonValueChanged, true);
            app.HideShowButton.Text = 'Hide';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayoutRight = uigridlayout(app.RightPanel);
            app.EnvInitialConditionPanel = uipanel(app.GridLayoutRight);
            app.EnvInitialConditionPanel.Tooltip = {'Setup the environment cndition for the EV simulation.'};
            app.EnvInitialConditionPanel.Title = 'Environment and Initial Condition';
            app.GridLayoutEnv = uigridlayout(app.EnvInitialConditionPanel);
            app.AmbTempLabel = uilabel(app.GridLayoutEnv);
            app.AmbTempLabel.Tooltip = {'Ambient temprature in deg C |ambient'};
            app.AmbTempLabel.Text = 'Ambient Temperature';
            app.AmbTempEditField = uieditfield(app.GridLayoutEnv, 'numeric');
            app.AmbTempEditField.Tooltip = {'default (25 degC)'};
            app.AmbTempEditField.Value = 25;
            app.AmbientPressureInitialLabel = uilabel(app.GridLayoutEnv);
            app.AmbientPressureInitialLabel.Tooltip = {'Ambient pressure in bar for initialization | cabin_p_init'};
            app.AmbientPressureInitialLabel.Text = 'Ambient Pressure Initial';
            app.AmbPressInitEditField = uieditfield(app.GridLayoutEnv, 'numeric');
            app.AmbPressInitEditField.Tooltip = {'default(1 bar)'};
            app.AmbPressInitEditField.Value = 1;
            app.RelativeHumidityInitialLabel = uilabel(app.GridLayoutEnv);
            app.RelativeHumidityInitialLabel.Tooltip = {'Cabin relative humidity initialization | cabin_RH_init'};
            app.RelativeHumidityInitialLabel.Text = 'Relative Humidity Initial';
            app.RelHumidityInitEditField = uieditfield(app.GridLayoutEnv, 'numeric');
            app.RelHumidityInitEditField.Tooltip = {'default (0.3)'};
            app.RelHumidityInitEditField.Value = 0.3;
            app.CO2FractionInitialEditFieldLabel = uilabel(app.GridLayoutEnv);
            app.CO2FractionInitialEditFieldLabel.Tooltip = {'Cabin CO2 fraction initialization | cabin_CO2_init'};
            app.CO2FractionInitialEditFieldLabel.Text = 'CO2 Fraction Initial';
            app.CO2FractionInitialEditField = uieditfield(app.GridLayoutEnv, 'numeric');
            app.CO2FractionInitialEditField.Tooltip = {'default (0.0004)'};
            app.CO2FractionInitialEditField.Value = 0.0004;
            app.degCLabel = uilabel(app.GridLayoutEnv);
            app.degCLabel.Text = 'degC';
            app.barLabel = uilabel(app.GridLayoutEnv);
            app.barLabel.Text = 'bar';
            app.HVACPanel = uipanel(app.GridLayoutRight);
            app.HVACPanel.Tooltip = {'Select HVAC dashboard settigs'};
            app.HVACPanel.Title = 'HVAC';
            app.GridLayoutHhvac = uigridlayout(app.HVACPanel);
            app.GridLayoutHhvac.Tooltip = {'Cabin HVAC settings.'};
            app.ACButton = uibutton(app.GridLayoutHhvac, 'state');
            app.ACButton.ValueChangedFcn = createCallbackFcn(app, @ACButtonValueChanged, true);
            app.ACButton.Tooltip = {'Select AC on or Off for cabin'};
            app.ACButton.Text = 'Off';
            app.CabinTempSetpointLabel = uilabel(app.GridLayoutHhvac);
            app.CabinTempSetpointLabel.Tooltip = {'Setpoint for cabin temperature | CabinSpTp'};
            app.CabinTempSetpointLabel.Text = {'Cabin Temperature '; 'Setpoint'};
            app.CabinTempSetpointEditField = uieditfield(app.GridLayoutHhvac, 'numeric');
            app.CabinTempSetpointEditField.Tooltip = {'default (20)'};
            app.CabinTempSetpointEditField.Value = 20;
            app.CabindegCLabel = uilabel(app.GridLayoutHhvac);
            app.CabindegCLabel.Text = 'degC';
            app.ACstatusLabel = uilabel(app.GridLayoutHhvac);
            app.ACstatusLabel.Text = 'AC status';
            app.DriverSetupPanel = uipanel(app.GridLayoutRight);
            app.DriverSetupPanel.Tooltip = {'Driver and control setup.'};
            app.DriverSetupPanel.Title = 'Driver Setup';
            app.GridLayoutDriver = uigridlayout(app.DriverSetupPanel);
            app.AWDButton = uibutton(app.GridLayoutDriver, 'state');
            app.AWDButton.ValueChangedFcn = createCallbackFcn(app, @AWDButtonValueChanged, true);
            app.AWDButton.Tooltip = {'Click to activate All-Wheel drive mode, default is FWD'};
            app.AWDButton.Text = 'AWD On';
            app.AWDButton.Value = true;
            app.RegenButton = uibutton(app.GridLayoutDriver, 'state');
            app.RegenButton.ValueChangedFcn = createCallbackFcn(app, @RegenButtonValueChanged, true);
            app.RegenButton.Tooltip = {'Click to engage regenerative braking'};
            app.RegenButton.Text = 'Regen On';
            app.RegenButton.Value = true;
            app.ChargingButton = uibutton(app.GridLayoutDriver, 'state');
            app.ChargingButton.ValueChangedFcn = createCallbackFcn(app, @ChargingButtonValueChanged, true);
            app.ChargingButton.Tooltip = {'Put the vehicle in charging mode'};
            app.ChargingButton.Text = 'Charging Off';
            app.Panel = uipanel(app.GridLayoutDriver);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.ControlSelectionLabel = uilabel(app.GridLayout2);
            app.ControlSelectionLabel.Text = {'Control '; 'Selection'};
            app.ControlSelectionDropDown = uidropdown(app.GridLayout2);
            app.ControlSelectionDropDown.Items = {'No control selected'};
            app.ControlSelectionDropDown.Tooltip = {'Select the control subsystem for the model'};
            app.ControlSelectionDropDown.Value = 'No control selected';
            app.DriveCycleDropDownLabel = uilabel(app.GridLayout2);
            app.DriveCycleDropDownLabel.Text = {'Drive'; 'Cycle'};
            app.DriveCycleDropDown = uidropdown(app.GridLayout2);
            app.DriveCycleDropDown.Items = {'No drive cycle selected'};
            app.DriveCycleDropDown.ValueChangedFcn = createCallbackFcn(app, @DriveCycleDropDownValueChanged, true);
            app.DriveCycleDropDown.Tooltip = {'Select drive cycle for the model'};
            app.DriveCycleDropDown.Value = 'No drive cycle selected';
            app.ControlDesc = uibutton(app.GridLayout2, 'push');
            app.ControlDesc.ButtonPushedFcn = createCallbackFcn(app, @ControlDescButtonPushed, true);
            app.ControlDesc.Tooltip = {'Click to see the description of controls block selected'};
            app.ControlDesc.Text = '?';
            app.DriveCycleDesc = uibutton(app.GridLayout2, 'push');
            app.DriveCycleDesc.ButtonPushedFcn = createCallbackFcn(app, @DriveCycleDescButtonPushed, true);
            app.DriveCycleDesc.Enable = 'off';
            app.DriveCycleDesc.Tooltip = {'Click to see the description of drive cycle'};
            app.DriveCycleDesc.Text = '?';
            app.ModelExportButton = uibutton(app.GridLayoutRight, 'push');
            app.ModelExportButton.ButtonPushedFcn = createCallbackFcn(app, @ModelExportButtonPushed, true);
            app.ModelExportButton.Enable = 'off';
            app.ModelExportButton.Tooltip = {'Create m script for model creation, which sets the base model to required configuration.'};
            app.ModelExportButton.Text = 'Export Model';
            app.ParameterExportButton = uibutton(app.GridLayoutRight, 'push');
            app.ParameterExportButton.ButtonPushedFcn = createCallbackFcn(app, @ParameterExportButtonPushed, true);
            app.ParameterExportButton.Enable = 'off';
            app.ParameterExportButton.Tooltip = {'Create the parameter script for the component selected.'};
            app.ParameterExportButton.Text = 'Export Parameter';
            app.CreateModelButton = uibutton(app.GridLayoutRight, 'push');
            app.CreateModelButton.ButtonPushedFcn = createCallbackFcn(app, @CreateModelButtonPushed, true);
            app.CreateModelButton.Enable = 'off';
            app.CreateModelButton.Tooltip = {'Setup the base model to the selected configuration and create parameter script for the model.'};
            app.CreateModelButton.Text = 'Model Setup';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BEVapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end